classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BalanceCAOUIFigure              matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        MainTabGroup                    matlab.ui.container.TabGroup
        EditeurdesimulationTab          matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        ParamtresdusimulateurPanel      matlab.ui.container.Panel
        GridLayout4                     matlab.ui.container.GridLayout
        LameLabel                       matlab.ui.control.Label
        LongueurdelalameEditFieldLabel  matlab.ui.control.Label
        LongueurdelalameEditField       matlab.ui.control.NumericEditField
        LargeurdelalamemEditFieldLabel  matlab.ui.control.Label
        LargeurdelalamemEditField       matlab.ui.control.NumericEditField
        EditFieldLabel                  matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        RgulateurdepositionLabel        matlab.ui.control.Label
        xEditFieldLabel                 matlab.ui.control.Label
        xEditField                      matlab.ui.control.NumericEditField
        K_iEditFieldLabel               matlab.ui.control.Label
        K_iEditField                    matlab.ui.control.NumericEditField
        ChargerparamtrespardfautButton  matlab.ui.control.Button
        SauvegarderButton               matlab.ui.control.Button
        GainEditFieldLabel              matlab.ui.control.Label
        GainEditField                   matlab.ui.control.NumericEditField
        ChargerparamtrespersonnalissButton  matlab.ui.control.Button
        AmplificateurdepuissanceLabel   matlab.ui.control.Label
        EntresPanel                     matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        talonnageautomatiqueCheckBox    matlab.ui.control.CheckBox
        MassedposegEditFieldLabel       matlab.ui.control.Label
        MassedposegEditField            matlab.ui.control.NumericEditField
        FonctiondemassevariablegEditFieldLabel  matlab.ui.control.Label
        FonctiondemassevariablegEditField  matlab.ui.control.EditField
        TextArea                        matlab.ui.control.TextArea
        SimulationPanel                 matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        LancerlasimulationButton        matlab.ui.control.Button
        DuredelasimulationsEditField_2Label  matlab.ui.control.Label
        DuredelasimulationsEditField_2  matlab.ui.control.EditField
        BalayagedeparamtresPanel        matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        ParamtrebalayDropDown_2Label    matlab.ui.control.Label
        ParamtrebalayDropDown_2         matlab.ui.control.DropDown
        OptionsdaffichagePanel          matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        ModeDropDown_2Label             matlab.ui.control.Label
        ModeDropDown_2                  matlab.ui.control.DropDown
        UnitsdemesureDropDown_2Label    matlab.ui.control.Label
        UnitsdemesureDropDown_2         matlab.ui.control.DropDown
        BalayerunparamtreButton_2       matlab.ui.control.Button
        ResultatsTab                    matlab.ui.container.Tab
        GridLayout8                     matlab.ui.container.GridLayout
        PerformancePanel                matlab.ui.container.Panel
        GraphiquesPanel                 matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        AxesMasseReelle                 matlab.ui.control.UIAxes
        Button                          matlab.ui.control.Button
    end

    
    properties (Access = private)
        x % Description
        DesignData % Data Dictionnary section for all model data (parameters)
        SimulationOutput % Description
    end
    
    methods (Access = private)
        function load_design_data(app, filepath)
            importFromFile(app.DesignData, filepath, 'existingVarsAction','overwrite');
            app.reset_parameters();
        end
        
        function load_default_design_data(app)
            app.load_design_data("default_design_data.mat")
        end
        
        function reset_parameters(app)
            app.GainEditField.Value = getValue(getEntry(app.DesignData, "K_amp"));
        end
        
        function UpdatePlots(app)
            disp(app.SimulationOutput)
            masseMesuree = app.SimulationOutput.logsout.getElement("masse_mesuree").Values; %.Time
            % time = k.logsout.getElement("masse_mesuree").Values.Data
            plot(app.AxesMasseReelle, masseMesuree.Time, masseMesuree.Data)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            dataDictionnary = Simulink.data.dictionary.open("dict.sldd");
            app.DesignData = getSection(dataDictionnary, "Design Data");
            app.load_default_design_data();
        end

        % Value changed function: xEditField
        function xEditFieldValueChanged(app, event)
            value = app.xEditField.Value;
            app.x = value;
            disp(app.x);
            setValue(getEntry(app.DesignData, "x"), value);
        end

        % Button pushed function: LancerlasimulationButton
        function LancerlasimulationButtonPushed(app, event)
            
            app.MainTabGroup.SelectedTab = app.MainTabGroup.Children(2);
            try
                set_param("main", 'SimulationCommand', 'stop')
            catch
            end
            try
                app.SimulationOutput = sim("main.slx");
            catch
            end
            set_param("main", 'SimulationCommand', 'start')
        end

        % Button pushed function: SauvegarderButton
        function SauvegarderButtonPushed(app, event)
            [file, path] = uiputfile("*.mat", "Sauvegarder les paramètres du simulateur");
            
            if ~isequal(file, 0)
                exportToFile(app.DesignData, fullfile(path, file));
            end
        end

        % Button pushed function: ChargerparamtrespersonnalissButton
        function ChargerparamtrespersonnalissButtonPushed(app, event)
            [file, path] = uigetfile("*.mat", "Charger les paramètres du simulateur");
            
            if ~isequal(file, 0)
                importFromFile(app.DesignData, fullfile(path, file), 'existingVarsAction','overwrite')
            end
        end

        % Button pushed function: ChargerparamtrespardfautButton
        function ChargerparamtrespardfautButtonPushed(app, event)
            app.load_default_design_data()
        end

        % Value changed function: GainEditField
        function GainEditFieldValueChanged(app, event)
            value = app.GainEditField.Value;
            setValue(getEntry(app.DesignData, "K_amp"), value);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.UpdatePlots()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BalanceCAOUIFigure = uifigure('Visible', 'off');
            app.BalanceCAOUIFigure.Name = 'Balance CAO';
            app.GridLayout = uigridlayout(app.BalanceCAOUIFigure);
            app.MainTabGroup = uitabgroup(app.GridLayout);
            app.EditeurdesimulationTab = uitab(app.MainTabGroup);
            app.EditeurdesimulationTab.Title = 'Éditeur de simulation';
            app.GridLayout2 = uigridlayout(app.EditeurdesimulationTab);
            app.ParamtresdusimulateurPanel = uipanel(app.GridLayout2);
            app.ParamtresdusimulateurPanel.Title = 'Paramètres du simulateur';
            app.GridLayout4 = uigridlayout(app.ParamtresdusimulateurPanel);
            app.LameLabel = uilabel(app.GridLayout4);
            app.LameLabel.Text = 'Lame';
            app.LongueurdelalameEditFieldLabel = uilabel(app.GridLayout4);
            app.LongueurdelalameEditFieldLabel.Text = 'Longueur de la lame';
            app.LongueurdelalameEditField = uieditfield(app.GridLayout4, 'numeric');
            app.LargeurdelalamemEditFieldLabel = uilabel(app.GridLayout4);
            app.LargeurdelalamemEditFieldLabel.Text = 'Largeur de la lame (m)';
            app.LargeurdelalamemEditField = uieditfield(app.GridLayout4, 'numeric');
            app.EditFieldLabel = uilabel(app.GridLayout4);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.GridLayout4, 'text');
            app.RgulateurdepositionLabel = uilabel(app.GridLayout4);
            app.RgulateurdepositionLabel.Text = 'Régulateur de position';
            app.xEditFieldLabel = uilabel(app.GridLayout4);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.GridLayout4, 'numeric');
            app.xEditField.ValueChangedFcn = createCallbackFcn(app, @xEditFieldValueChanged, true);
            app.K_iEditFieldLabel = uilabel(app.GridLayout4);
            app.K_iEditFieldLabel.Text = 'K_i';
            app.K_iEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ChargerparamtrespardfautButton = uibutton(app.GridLayout4, 'push');
            app.ChargerparamtrespardfautButton.ButtonPushedFcn = createCallbackFcn(app, @ChargerparamtrespardfautButtonPushed, true);
            app.ChargerparamtrespardfautButton.Text = 'Charger paramètres par défaut';
            app.SauvegarderButton = uibutton(app.GridLayout4, 'push');
            app.SauvegarderButton.ButtonPushedFcn = createCallbackFcn(app, @SauvegarderButtonPushed, true);
            app.SauvegarderButton.Text = 'Sauvegarder';
            app.ChargerparamtrespersonnalissButton = uibutton(app.GridLayout4, 'push');
            app.ChargerparamtrespersonnalissButton.ButtonPushedFcn = createCallbackFcn(app, @ChargerparamtrespersonnalissButtonPushed, true);
            app.ChargerparamtrespersonnalissButton.Text = 'Charger paramètres personnalisés';
            app.AmplificateurdepuissanceLabel = uilabel(app.GridLayout4);
            app.AmplificateurdepuissanceLabel.Text = 'Amplificateur de puissance';
            app.GainEditFieldLabel = uilabel(app.GridLayout4);
            app.GainEditFieldLabel.Text = 'Gain';
            app.GainEditField = uieditfield(app.GridLayout4, 'numeric');
            app.GainEditField.ValueChangedFcn = createCallbackFcn(app, @GainEditFieldValueChanged, true);
            app.EntresPanel = uipanel(app.GridLayout2);
            app.EntresPanel.Title = 'Entrées';
            app.GridLayout5 = uigridlayout(app.EntresPanel);
            app.talonnageautomatiqueCheckBox = uicheckbox(app.GridLayout5);
            app.talonnageautomatiqueCheckBox.Text = 'Étalonnage automatique';
            app.talonnageautomatiqueCheckBox.Value = true;
            app.MassedposegEditFieldLabel = uilabel(app.GridLayout5);
            app.MassedposegEditFieldLabel.Text = 'Masse déposée (g)';
            app.MassedposegEditField = uieditfield(app.GridLayout5, 'numeric');
            app.MassedposegEditField.Value = 50;
            app.FonctiondemassevariablegEditFieldLabel = uilabel(app.GridLayout5);
            app.FonctiondemassevariablegEditFieldLabel.Text = 'Fonction de masse variable (g)';
            app.FonctiondemassevariablegEditField = uieditfield(app.GridLayout5, 'text');
            app.FonctiondemassevariablegEditField.Enable = 'off';
            app.TextArea = uitextarea(app.GridLayout5);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Expliquation fonction de masse variable. Lorem ipsum dolor sit amet, consectetur adipiscing elit. Curabitur nec risus feugiat, condimentum elit vel, maximus diam. Maecenas massa est, consequat et lacus non, imperdiet vehicula dolor. Sed enim tortor, laoreet vel dictum id, faucibus vitae leo. Phasellus eget pharetra mi, eu eleifend erat. Praesent nec tincidunt nibh. Cras sit amet sapien vel nibh lobortis porttitor. Pellentesque sit amet gravida neque. Vivamus risus mauris, lobortis sed metus ac, pretium ullamcorper risus. Integer fringilla pulvinar urna vel luctus. Nam ullamcorper nulla erat, ac gravida nunc semper eu. Integer sed turpis bibendum, consectetur augue quis, varius nisi.'; ''; 'Duis id metus velit. Aliquam erat volutpat. Curabitur tristique magna at commodo molestie. Proin ultrices quam quis nulla tristique iaculis. Phasellus a dapibus ligula, quis auctor nulla. Vivamus eget ante ipsum. Fusce sodales quam ligula, sit amet blandit arcu posuere in. Cras convallis, mi sed scelerisque varius, lacus velit gravida massa, in hendrerit mi nisi quis orci. Praesent a felis et nisl accumsan cursus. Phasellus nec eros eu massa egestas venenatis a vitae elit. Integer ac est a quam aliquet facilisis eu tincidunt risus. Orci varius natoque penatibus et magnis dis parturient montes, nascetur ridiculus mus. Nunc consectetur rhoncus neque, ut ullamcorper lectus imperdiet a. Nam quis ipsum ornare, ultricies nisl a, convallis mauris.'};
            app.SimulationPanel = uipanel(app.GridLayout2);
            app.SimulationPanel.Title = 'Simulation';
            app.GridLayout3 = uigridlayout(app.SimulationPanel);
            app.LancerlasimulationButton = uibutton(app.GridLayout3, 'push');
            app.LancerlasimulationButton.ButtonPushedFcn = createCallbackFcn(app, @LancerlasimulationButtonPushed, true);
            app.LancerlasimulationButton.Text = 'Lancer la simulation';
            app.DuredelasimulationsEditField_2Label = uilabel(app.GridLayout3);
            app.DuredelasimulationsEditField_2Label.Text = 'Durée de la simulation (s)';
            app.DuredelasimulationsEditField_2 = uieditfield(app.GridLayout3, 'text');
            app.DuredelasimulationsEditField_2.Value = '24';
            app.BalayagedeparamtresPanel = uipanel(app.GridLayout2);
            app.BalayagedeparamtresPanel.Title = 'Balayage de paramètres';
            app.GridLayout6 = uigridlayout(app.BalayagedeparamtresPanel);
            app.ParamtrebalayDropDown_2Label = uilabel(app.GridLayout6);
            app.ParamtrebalayDropDown_2Label.Text = 'Paramètre balayé';
            app.ParamtrebalayDropDown_2 = uidropdown(app.GridLayout6);
            app.ParamtrebalayDropDown_2.Items = {'K_amp', 'K_p'};
            app.ParamtrebalayDropDown_2.Value = 'K_amp';
            app.BalayerunparamtreButton_2 = uibutton(app.GridLayout6, 'push');
            app.BalayerunparamtreButton_2.Text = 'Balayer un paramètre';
            app.OptionsdaffichagePanel = uipanel(app.GridLayout2);
            app.OptionsdaffichagePanel.Title = 'Options d''affichage';
            app.GridLayout7 = uigridlayout(app.OptionsdaffichagePanel);
            app.ModeDropDown_2Label = uilabel(app.GridLayout7);
            app.ModeDropDown_2Label.Text = 'Mode';
            app.ModeDropDown_2 = uidropdown(app.GridLayout7);
            app.ModeDropDown_2.Items = {'Masse', 'Comptage de pièces'};
            app.ModeDropDown_2.Value = 'Masse';
            app.UnitsdemesureDropDown_2Label = uilabel(app.GridLayout7);
            app.UnitsdemesureDropDown_2Label.Text = 'Unités de mesure';
            app.UnitsdemesureDropDown_2 = uidropdown(app.GridLayout7);
            app.UnitsdemesureDropDown_2.Items = {'grammes', 'onces'};
            app.UnitsdemesureDropDown_2.Value = 'grammes';
            app.ResultatsTab = uitab(app.MainTabGroup);
            app.ResultatsTab.Title = 'Résultats';
            app.GridLayout8 = uigridlayout(app.ResultatsTab);
            app.PerformancePanel = uipanel(app.GridLayout8);
            app.PerformancePanel.Title = 'Performance';
            app.GraphiquesPanel = uipanel(app.GridLayout8);
            app.GraphiquesPanel.Title = 'Graphiques';
            app.GridLayout9 = uigridlayout(app.GraphiquesPanel);
            app.AxesMasseReelle = uiaxes(app.GridLayout9);
            title(app.AxesMasseReelle, 'Masse réelle')
            xlabel(app.AxesMasseReelle, 'Temps (s)')
            ylabel(app.AxesMasseReelle, 'Masse (g)')
            app.Button = uibutton(app.GridLayout9, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            % Show the figure after all components are created
            app.BalanceCAOUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BalanceCAOUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BalanceCAOUIFigure)
        end
    end
end